classdef IAN_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataSplitTab                  matlab.ui.container.Tab
        AutoCheckBox                  matlab.ui.control.CheckBox
        BetaSpinner                   matlab.ui.control.Spinner
        BetaSpinnerLabel              matlab.ui.control.Label
        AlphaSpinner                  matlab.ui.control.Spinner
        AlphaSpinnerLabel             matlab.ui.control.Label
        MaxSpinner                    matlab.ui.control.Spinner
        MaxSpinnerLabel               matlab.ui.control.Label
        MinSpinner                    matlab.ui.control.Spinner
        MinSpinnerLabel               matlab.ui.control.Label
        NormalizationDropDown         matlab.ui.control.DropDown
        NormalizationDropDownLabel    matlab.ui.control.Label
        ValidationRatioSpinnerLabel   matlab.ui.control.Label
        ValidationRatioSpinner        matlab.ui.control.Spinner
        inputfirstLabel               matlab.ui.control.Label
        DataSplitButton               matlab.ui.control.Button
        TestRatioSpinnerLabel         matlab.ui.control.Label
        TestRatioSpinner              matlab.ui.control.Spinner
        OptimizationDropDownLabel     matlab.ui.control.Label
        OptimizationDropDown          matlab.ui.control.DropDown
        GeneralizationDropDownLabel   matlab.ui.control.Label
        GeneralizationDropDown        matlab.ui.control.DropDown
        TargetButton                  matlab.ui.control.Button
        DataImportLabel               matlab.ui.control.Label
        InputButton                   matlab.ui.control.Button
        TrainingTab                   matlab.ui.container.Tab
        HistoryDataExportPanel        matlab.ui.container.Panel
        alphabetaCheckBox             matlab.ui.control.CheckBox
        muCheckBox                    matlab.ui.control.CheckBox
        StopButton                    matlab.ui.control.Button
        TrainingButton                matlab.ui.control.Button
        ofLayerLabel                  matlab.ui.control.Label
        ofLayerSpinner                matlab.ui.control.Spinner
        PatienceSpinnerLabel          matlab.ui.control.Label
        PatienceSpinner               matlab.ui.control.Spinner
        MaxEpochSpinnerLabel          matlab.ui.control.Label
        MaxEpochSpinner               matlab.ui.control.Spinner
        RepetitionSpinnerLabel        matlab.ui.control.Label
        RepetitionSpinner             matlab.ui.control.Spinner
        UITable                       matlab.ui.control.Table
        IANLabel                      matlab.ui.control.Label
        InhaASCENTNeuralnetworkLabel  matlab.ui.control.Label
        beta17Label                   matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function updateTableFcn(app)
             M = app.ofLayerSpinner.Value;
            celldata = {10;'Tanh';'Glorot'}';
            for i=2:M
                celldata{i,1} = 10;
                celldata{i,2} = {'Tanh'};
                celldata{i,3} = {'Glorot'};
            end
            
            celldata{M,1} = NaN; % 마지막 layer의 neuron 값은 의미가 없으므로 0으로 설정
            
            t = cell2table(celldata);
            t.Properties.VariableNames = {'Neuron','Transfer','Kernel'};
            cats = categorical(t.Transfer,{'Logsig','Tanh','Purelin','ReLU','LeakyReLU','ELU','SELU'});
            t.Transfer = cats;
            dogs = categorical(t.Kernel,{'Glorot', 'Lucun', 'He'});
            t.Kernel = dogs;

            app.UITable.Data = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [fileinput,pathinput] = uigetfile('*.txt','Load file');
            fout = fopen('splitinfo.log', 'w');
            fprintf(fout, '%s%s\n', pathinput, fileinput);
            fclose(fout);
        end

        % Button pushed function: TargetButton
        function TargetButtonPushed(app, event)
            [filetarget,pathtarget] = uigetfile('*.txt','Load file');
            fout = fopen('splitinfo.log', 'a');
            fprintf(fout, '%s%s\n', pathtarget, filetarget);
            fclose(fout);
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)

            if app.GeneralizationDropDown.Value == "Early Stopping"
                generalization = 0;     % Early Stopping
            else
                generalization = 1;     % Regularization
            end
            
            if app.NormalizationDropDown.Value == "minmax"
                scaler = 0; % minmax_scaler
            else
                scaler = 1; % StandardScaler
            end
            
            repeat_no = app.RepetitionSpinner.Value;
            max_step = app.MaxEpochSpinner.Value;
            patience = app.PatienceSpinner.Value;
            M = app.ofLayerSpinner.Value;
            
            min_value = app.MinSpinner.Value;
            max_value = app.MaxSpinner.Value;
            
            TransFnc = zeros(M,1);
            kernel_init_value = zeros(M,1);            
            
            Neuron = app.UITable.DisplayData.Neuron;
            Trans = app.UITable.DisplayData.Transfer;  
            kernel_init = app.UITable.DisplayData.Kernel;

%             Neuron = zeros(M-1,1);

            for i=1:M
                if Trans(i) == "Logsig"
                    TransFnc(i) = 1;
                elseif Trans(i) == "Tanh"
                    TransFnc(i) = 2;
                elseif Trans(i) == "Purelin"
                    TransFnc(i) = 3;
                elseif Trans(i) == "ReLU"
                    TransFnc(i) = 4;
                elseif Trans(i) == "LeakyReLU"
                    TransFnc(i) = 5;
                elseif Trans(i) == "ELU"
                    TransFnc(i) = 6;
                else
                    TransFnc(i) = 7;
                end
            end
            
            for i=1:M
                if kernel_init(i) == "Glorot"
                    kernel_init_value(i) = 0;
                elseif kernel_init(i) == "LuCun"
                    kernel_init_value(i) = 1;
                else
                    kernel_init_value(i) = 2;
                end
            end
            
            % Parameter File outputlayer
            fout = fopen('info.log', 'w');
            fprintf(fout, '%d\n', generalization);
            fprintf(fout, '%d\n', scaler);
            if scaler == 0      % 0: minmax_scaler, 1: StandardScaler
                fprintf(fout, '%f\n', min_value);
                fprintf(fout, '%f\n', max_value);
            end
            fprintf(fout, '%d\n', repeat_no);
            fprintf(fout, '%d\n', max_step);
            
            if generalization == 0      % 0: Early Stopping, 1: L2 Regularization
                fprintf(fout, '%d\n', patience);
            else
                fprintf(fout, '%d\n', app.AutoCheckBox.Value);
                fprintf(fout, '%f\n', app.AlphaSpinner.Value);
                fprintf(fout, '%f\n', app.BetaSpinner.Value);
            end
            
            fprintf(fout, '%d\n', M);
            fprintf(fout, '%d\n', TransFnc);
            fprintf(fout, '%d\n', kernel_init_value);
            fprintf(fout, '%d\n', Neuron);
            
            fclose(fout);
            
            muout = 0;
            alphabetaout = 0;
            
            if app.muCheckBox.Value == true
                muout = 1;
            end
            
            if app.alphabetaCheckBox.Value == true
                alphabetaout = 1;
            end
            
            fout = fopen('hisout.txt','w'); % mu와 alpha, beta를 출력할지 말지 여부를 확인
            fprintf(fout, '%d\n', muout);
            if app.GeneralizationDropDown.Value == "Regularization"
                fprintf(fout, '%d\n', alphabetaout);
            end
            fclose(fout);
            
            % Run Training
            trainingCode
        end

        % Button pushed function: DataSplitButton
        function DataSplitButtonPushed(app, event)
            ValRatio  = app.ValidationRatioSpinner.Value;
            TestRatio = app.TestRatioSpinner.Value;
            
            % Parameter File outputlayer
            fout = fopen('splitinfo.log', 'a');
            
            fprintf(fout, '%f\n', ValRatio);
            fprintf(fout, '%f\n', TestRatio);
            
            fclose(fout);
            
%             startTableFcn(app);
            
            system('DataSplit.exe');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

        end

        % Value changed function: ValidationRatioSpinner
        function ValidationRatioSpinnerValueChanged(app, event)
            
        end

        % Drop down opening function: GeneralizationDropDown
        function GeneralizationDropDownOpening(app, event)

        end

        % Value changed function: GeneralizationDropDown
        function GeneralizationDropDownValueChanged(app, event)
            if app.GeneralizationDropDown.Value == "Regularization"
                app.ValidationRatioSpinnerLabel.Enable = 'off';
                app.ValidationRatioSpinner.Enable = 'off';
                app.ValidationRatioSpinner.Value = 0;
                
                app.PatienceSpinner.Enable = 'off';
                app.PatienceSpinnerLabel.Enable = 'off';
                
%                 app.alphabetaCheckBox.Enable = 'on';
                
                app.AlphaSpinner.Enable = 'on';
                app.BetaSpinner.Enable = 'on';
                app.AlphaSpinnerLabel.Enable = 'on';
                app.BetaSpinnerLabel.Enable = 'on';
                app.AutoCheckBox.Enable = 'on';
                
            else
                app.ValidationRatioSpinnerLabel.Enable = 'on';
                app.ValidationRatioSpinner.Enable = 'on';
                
                app.PatienceSpinner.Enable = 'on';
                app.PatienceSpinnerLabel.Enable = 'on';
                
%                 app.alphabetaCheckBox.Enable = 'off';
                
                app.AlphaSpinner.Enable = 'off';
                app.BetaSpinner.Enable = 'off';
                app.AlphaSpinnerLabel.Enable = 'off';
                app.BetaSpinnerLabel.Enable = 'off';
                app.AutoCheckBox.Enable = 'off';
            end
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
         
        end

        % Value changed function: ofLayerSpinner
        function ofLayerSpinnerValueChanged(app, event)
            updateTableFcn(app)
        end

        % Value changed function: OptimizationDropDown
        function OptimizationDropDownValueChanged(app, event)

        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            if app.NormalizationDropDown.Value == "Standard"
                app.MinSpinnerLabel.Enable = 'off';
                app.MinSpinner.Enable = 'off';
                app.MinSpinner.Value = 0;
                
                app.MaxSpinnerLabel.Enable = 'off';
                app.MaxSpinner.Enable = 'off';
                app.MaxSpinner.Value = 1;
            else
                app.MinSpinnerLabel.Enable = 'on';
                app.MinSpinner.Enable = 'on';
                
                app.MaxSpinnerLabel.Enable = 'on';
                app.MaxSpinner.Enable = 'on';
            end
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            if app.AutoCheckBox.Value == 1
                app.alphabetaCheckBox.Enable = 'on';
            else
                app.alphabetaCheckBox.Enable = 'off';
                app.alphabetaCheckBox.Value = 0;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'inha logo.png';
            app.beta17Label = uilabel(app.UIFigure);
            app.beta17Label.Text = 'beta 1.7';
            app.InhaASCENTNeuralnetworkLabel = uilabel(app.UIFigure);
            app.InhaASCENTNeuralnetworkLabel.Text = 'Inha ASCENT Neural network';
            app.IANLabel = uilabel(app.UIFigure);
            app.IANLabel.Text = 'IAN';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataSplitTab = uitab(app.TabGroup);
            app.DataSplitTab.Title = 'Data Split';
            app.InputButton = uibutton(app.DataSplitTab, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.DataImportLabel = uilabel(app.DataSplitTab);
            app.DataImportLabel.Text = 'Data Import';
            app.TargetButton = uibutton(app.DataSplitTab, 'push');
            app.TargetButton.ButtonPushedFcn = createCallbackFcn(app, @TargetButtonPushed, true);
            app.TargetButton.Text = 'Target';
            app.GeneralizationDropDown = uidropdown(app.DataSplitTab);
            app.GeneralizationDropDown.Items = {'Early Stopping', 'Regularization'};
            app.GeneralizationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GeneralizationDropDownOpening, true);
            app.GeneralizationDropDown.ValueChangedFcn = createCallbackFcn(app, @GeneralizationDropDownValueChanged, true);
            app.GeneralizationDropDown.Value = 'Early Stopping';
            app.GeneralizationDropDownLabel = uilabel(app.DataSplitTab);
            app.GeneralizationDropDownLabel.Text = 'Generalization';
            app.OptimizationDropDown = uidropdown(app.DataSplitTab);
            app.OptimizationDropDown.Items = {'LMBP'};
            app.OptimizationDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationDropDownValueChanged, true);
            app.OptimizationDropDown.Value = 'LMBP';
            app.OptimizationDropDownLabel = uilabel(app.DataSplitTab);
            app.OptimizationDropDownLabel.Text = 'Optimization';
            app.TestRatioSpinner = uispinner(app.DataSplitTab);
            app.TestRatioSpinner.Step = 0.01;
            app.TestRatioSpinner.Limits = [0 1];
            app.TestRatioSpinnerLabel = uilabel(app.DataSplitTab);
            app.TestRatioSpinnerLabel.Text = 'Test Ratio';
            app.DataSplitButton = uibutton(app.DataSplitTab, 'push');
            app.DataSplitButton.ButtonPushedFcn = createCallbackFcn(app, @DataSplitButtonPushed, true);
            app.DataSplitButton.Text = 'Data Split';
            app.inputfirstLabel = uilabel(app.DataSplitTab);
            app.inputfirstLabel.Text = '(input first)';
            app.ValidationRatioSpinner = uispinner(app.DataSplitTab);
            app.ValidationRatioSpinner.Step = 0.01;
            app.ValidationRatioSpinner.Limits = [0 1];
            app.ValidationRatioSpinner.ValueChangedFcn = createCallbackFcn(app, @ValidationRatioSpinnerValueChanged, true);
            app.ValidationRatioSpinnerLabel = uilabel(app.DataSplitTab);
            app.ValidationRatioSpinnerLabel.Text = 'Validation Ratio';
            app.NormalizationDropDownLabel = uilabel(app.DataSplitTab);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.DataSplitTab);
            app.NormalizationDropDown.Items = {'minmax', 'Standard'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Value = 'minmax';
            app.MinSpinnerLabel = uilabel(app.DataSplitTab);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.DataSplitTab);
            app.MinSpinner.Step = 0.5;
            app.MaxSpinnerLabel = uilabel(app.DataSplitTab);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.DataSplitTab);
            app.MaxSpinner.Step = 0.5;
            app.MaxSpinner.Value = 1;
            app.AlphaSpinnerLabel = uilabel(app.DataSplitTab);
            app.AlphaSpinnerLabel.Enable = 'off';
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.DataSplitTab);
            app.AlphaSpinner.Step = 0.001;
            app.AlphaSpinner.Limits = [0 1];
            app.AlphaSpinner.Enable = 'off';
            app.AlphaSpinner.Value = 0.1;
            app.BetaSpinnerLabel = uilabel(app.DataSplitTab);
            app.BetaSpinnerLabel.Enable = 'off';
            app.BetaSpinnerLabel.Text = 'Beta';
            app.BetaSpinner = uispinner(app.DataSplitTab);
            app.BetaSpinner.Step = 0.001;
            app.BetaSpinner.Limits = [0 1];
            app.BetaSpinner.Enable = 'off';
            app.BetaSpinner.Value = 0.9;
            app.AutoCheckBox = uicheckbox(app.DataSplitTab);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Enable = 'off';
            app.AutoCheckBox.Text = 'Auto';
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.UITable = uitable(app.TrainingTab);
            app.UITable.ColumnName = {'Neuron'; 'Activation Fn.'; 'Kernel init.'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UITable.ColumnSortable = [false false false];
            app.UITable.ColumnEditable = [true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RepetitionSpinner = uispinner(app.TrainingTab);
            app.RepetitionSpinner.Limits = [0 10];
            app.RepetitionSpinner.Value = 5;
            app.RepetitionSpinnerLabel = uilabel(app.TrainingTab);
            app.RepetitionSpinnerLabel.Text = 'Repetition';
            app.MaxEpochSpinner = uispinner(app.TrainingTab);
            app.MaxEpochSpinner.Limits = [1 Inf];
            app.MaxEpochSpinner.Value = 500;
            app.MaxEpochSpinnerLabel = uilabel(app.TrainingTab);
            app.MaxEpochSpinnerLabel.Text = 'Max. Epoch';
            app.PatienceSpinner = uispinner(app.TrainingTab);
            app.PatienceSpinner.Limits = [0 Inf];
            app.PatienceSpinner.Value = 20;
            app.PatienceSpinnerLabel = uilabel(app.TrainingTab);
            app.PatienceSpinnerLabel.Text = 'Patience';
            app.ofLayerSpinner = uispinner(app.TrainingTab);
            app.ofLayerSpinner.Limits = [1 10];
            app.ofLayerSpinner.ValueChangedFcn = createCallbackFcn(app, @ofLayerSpinnerValueChanged, true);
            app.ofLayerSpinner.Value = 2;
            app.ofLayerLabel = uilabel(app.TrainingTab);
            app.ofLayerLabel.Text = '# of Layer';
            app.TrainingButton = uibutton(app.TrainingTab, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.StopButton = uibutton(app.TrainingTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HistoryDataExportPanel = uipanel(app.TrainingTab);
            app.HistoryDataExportPanel.Title = 'History Data Export';
            app.muCheckBox = uicheckbox(app.HistoryDataExportPanel);
            app.muCheckBox.Text = 'mu';
            app.muCheckBox.Value = true;
            app.alphabetaCheckBox = uicheckbox(app.HistoryDataExportPanel);
            app.alphabetaCheckBox.Enable = 'off';
            app.alphabetaCheckBox.Text = 'α, β';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAN_beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end